classdef FrontPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure        % Mrkvicka9000 (Fro...
        label_gp_reg_00         matlab.ui.control.Label % N/A
        label_gp_reg_01         matlab.ui.control.Label % N/A
        label_gp_reg_02         matlab.ui.control.Label % N/A
        label_gp_reg_03         matlab.ui.control.Label % N/A
        label_gp_reg_04         matlab.ui.control.Label % N/A
        label_gp_reg_05         matlab.ui.control.Label % N/A
        label_gp_reg_06         matlab.ui.control.Label % N/A
        label_gp_reg_07         matlab.ui.control.Label % N/A
        label_gp_reg_08         matlab.ui.control.Label % N/A
        label_gp_reg_09         matlab.ui.control.Label % N/A
        label_gp_reg_10         matlab.ui.control.Label % N/A
        label_gp_reg_11         matlab.ui.control.Label % N/A
        label_gp_reg_12         matlab.ui.control.Label % N/A
        label_gp_reg_13         matlab.ui.control.Label % N/A
        label_gp_reg_14         matlab.ui.control.Label % N/A
        label_gp_reg_15         matlab.ui.control.Label % N/A
        label_program_counter   matlab.ui.control.Label % Program Counter   
        label_instruction       matlab.ui.control.Label % Instruction   
        label_return_address    matlab.ui.control.Label % Return Address   
        label_stack_pointer     matlab.ui.control.Label % Stack Pointer   
        label_keyboard          matlab.ui.control.Label % Keyboard   
        label_status_register   matlab.ui.control.Label % Status Register   
        label_random_number     matlab.ui.control.Label % Random Number   
        label_program_counter_value matlab.ui.control.Label % N/A
        label_instruction_value matlab.ui.control.Label % N/A
        label_stack_pointer_value matlab.ui.control.Label % N/A
        label_return_address_value matlab.ui.control.Label % N/A
        label_status_register_value matlab.ui.control.Label % N/A
        label_keyboard_value    matlab.ui.control.Label % N/A
        label_random_number_value matlab.ui.control.Label % N/A
        Label                   matlab.ui.control.Label % General Purpose R...
        label_stack_00          matlab.ui.control.Label % N/A
        label_stack_01          matlab.ui.control.Label % N/A
        label_stack_02          matlab.ui.control.Label % N/A
        label_stack_03          matlab.ui.control.Label % N/A
        label_stack_04          matlab.ui.control.Label % N/A
        label_stack_05          matlab.ui.control.Label % N/A
        label_stack_06          matlab.ui.control.Label % N/A
        label_stack_07          matlab.ui.control.Label % N/A
        label_stack_08          matlab.ui.control.Label % N/A
        label_stack_09          matlab.ui.control.Label % N/A
        label_stack_10          matlab.ui.control.Label % N/A
        label_stack_11          matlab.ui.control.Label % N/A
        label_stack_12          matlab.ui.control.Label % N/A
        label_stack_13          matlab.ui.control.Label % N/A
        label_stack_14          matlab.ui.control.Label % N/A
        label_stack_15          matlab.ui.control.Label % N/A
        stack                   matlab.ui.control.Label % Stack
        label_opcode_value      matlab.ui.control.Label % N/A
        label_dest_reg_value    matlab.ui.control.Label % N/A
        label_src_reg_value     matlab.ui.control.Label % N/A
        label_immediate_value   matlab.ui.control.Label % N/A
        Label2                  matlab.ui.control.Label %       Op      Des...
    end

    properties (Access = public)
        t    % Timer
        inst % Instruction
        def  % Definition
    end

    methods (Access = public)
        function UpdateOutputCb(app, obj, event)

            program_counter = evalin('base', 'program_counter');
            instruction     = evalin('base', 'instruction');
            stack_pointer   = evalin('base', 'stack_pointer');
            return_address  = evalin('base', 'return_address');
            status_register = evalin('base', 'status_register');
            keyboard        = evalin('base', 'keyboard');
            random_number   = evalin('base', 'random_number');
            
            app.label_program_counter_value.Text = sprintf('0x%04X  (%05d)', program_counter, program_counter);
            app.label_instruction_value.Text     = sprintf('0x%04X  (%05d)', instruction,     instruction);
            app.label_stack_pointer_value.Text   = sprintf('0x%04X  (%05d)', stack_pointer,   stack_pointer);
            app.label_return_address_value.Text  = sprintf('0x%04X  (%05d)', return_address,  return_address);
            app.label_status_register_value.Text = sprintf('0x%04X  (%05d)', status_register, status_register);
            app.label_keyboard_value.Text        = sprintf('0x%04X  (%05d)', keyboard,        keyboard);
            app.label_random_number_value.Text   = sprintf('0x%04X  (%05d)', random_number,   random_number);

            dest_reg  = evalin('base', 'dest_reg');
            src_reg   = evalin('base', 'src_reg');
            immediate = evalin('base', 'immediate');

            instr_msb = bitshift(instruction, -8);
            switch (bitand(instr_msb, app.def.INSTR_FORMAT_MASK))
        	case app.def.INSTR_FORMAT_0
		    switch (bitor(bitand(instr_msb, app.def.FORMAT_0_OPCODE_MASK), app.def.INSTR_FORMAT_0))
                        case app.inst.CALL,      app.label_opcode_value.Text = 'CALL'; 
                        case app.inst.JMP,       app.label_opcode_value.Text = 'JMP';
                        case app.inst.JPE,       app.label_opcode_value.Text = 'JPE';
                        case app.inst.JLT,       app.label_opcode_value.Text = 'JLT';
                        otherwise                app.label_opcode_value.Text = 'N/A';
                    end
                    app.label_dest_reg_value.Text  = 'N/A';
                    app.label_src_reg_value.Text   = 'N/A';
                    app.label_immediate_value.Text = sprintf('0x%04X', immediate);
                    
            	case app.def.INSTR_FORMAT_1
		    switch (bitor(bitand(instr_msb, app.def.FORMAT_1_OPCODE_MASK), app.def.INSTR_FORMAT_1))
                        case app.inst.CMPI,      app.label_opcode_value.Text = 'CMPI';
                        case app.inst.STOREI,    app.label_opcode_value.Text = 'STOREI';
                        case app.inst.SHIFTI,    app.label_opcode_value.Text = 'SHIFTI';
                        otherwise                app.label_opcode_value.Text = 'N/A';
                    end
                    app.label_dest_reg_value.Text  = 'N/A';
                    app.label_src_reg_value.Text   = sprintf('r%d', src_reg);
                    app.label_immediate_value.Text = sprintf('0x%04X', immediate);

		case app.def.INSTR_FORMAT_2
  		    switch (bitor(bitand(instr_msb, app.def.FORMAT_2_OPCODE_MASK), app.def.INSTR_FORMAT_2))
                        case app.inst.ADDI,      app.label_opcode_value.Text = 'ADDI';
                        case app.inst.LOADI,     app.label_opcode_value.Text = 'LOADI';
                        case app.inst.MOVU,      app.label_opcode_value.Text = 'MOVU';
                        case app.inst.MOVL,      app.label_opcode_value.Text = 'MOVL';
                        otherwise                app.label_opcode_value.Text = 'N/A';
                    end
                    app.label_dest_reg_value.Text  = sprintf('r%d', dest_reg);
                    app.label_src_reg_value.Text   = 'N/A';
                    app.label_immediate_value.Text = sprintf('0x%04X', immediate);

		case app.def.INSTR_FORMAT_3
		    switch (bitor(bitand(instr_msb, app.def.FORMAT_3_OPCODE_MASK), app.def.INSTR_FORMAT_3))
                        case app.inst.NOT,       app.label_opcode_value.Text = 'NOT';
                        case app.inst.XOR,       app.label_opcode_value.Text = 'XOR';
                        case app.inst.OR,        app.label_opcode_value.Text = 'OR';
                        case app.inst.AND,       app.label_opcode_value.Text = 'AND';
                        case app.inst.LOADL,     app.label_opcode_value.Text = 'LOADL';
                        case app.inst.LOADU,     app.label_opcode_value.Text = 'LOADU';
                        case app.inst.CMP,       app.label_opcode_value.Text = 'CMP';
                        case app.inst.RET,       app.label_opcode_value.Text = 'RET';
                        case app.inst.POP,       app.label_opcode_value.Text = 'POP';
                        case app.inst.PUSH,      app.label_opcode_value.Text = 'PUSH';
                        case app.inst.SHIFT,     app.label_opcode_value.Text = 'SHIFT';
                        case app.inst.ADD,       app.label_opcode_value.Text = 'ADD';
                        case app.inst.NOT_USED,  app.label_opcode_value.Text = 'NOT_USED';
                        case app.inst.STOREL,    app.label_opcode_value.Text = 'STOREL';
                        case app.inst.STOREU,    app.label_opcode_value.Text = 'STOREU';
                        case app.inst.STORE,     app.label_opcode_value.Text = 'STORE';
                        case app.inst.LOAD,      app.label_opcode_value.Text = 'LOAD';
                        case app.inst.MOV,       app.label_opcode_value.Text = 'MOV';
                        otherwise                app.label_opcode_value.Text = 'N/A';
                    end
                    app.label_dest_reg_value.Text  = sprintf('r%d', dest_reg);
                    app.label_src_reg_value.Text   = sprintf('r%d', src_reg);
                    app.label_immediate_value.Text = 'N/A';

                otherwise
                   app.label_opcode_value.Text    = 'N/A';
                   app.label_dest_reg_value.Text  = 'N/A';
                   app.label_src_reg_value.Text   = 'N/A';
                   app.label_immediate_value.Text = 'N/A';
            end
            
            gp_reg_00 = evalin('base', 'gp_reg_00');
            gp_reg_01 = evalin('base', 'gp_reg_01');
            gp_reg_02 = evalin('base', 'gp_reg_02');
            gp_reg_03 = evalin('base', 'gp_reg_03');
            gp_reg_04 = evalin('base', 'gp_reg_04');
            gp_reg_05 = evalin('base', 'gp_reg_05');
            gp_reg_06 = evalin('base', 'gp_reg_06');
            gp_reg_07 = evalin('base', 'gp_reg_07');
            gp_reg_08 = evalin('base', 'gp_reg_08');
            gp_reg_09 = evalin('base', 'gp_reg_09');
            gp_reg_10 = evalin('base', 'gp_reg_10');
            gp_reg_11 = evalin('base', 'gp_reg_11');
            gp_reg_12 = evalin('base', 'gp_reg_12');
            gp_reg_13 = evalin('base', 'gp_reg_13');
            gp_reg_14 = evalin('base', 'gp_reg_14');
            gp_reg_15 = evalin('base', 'gp_reg_15');
            
            app.label_gp_reg_00.Text = sprintf('00  0x%04X  (%05d)', gp_reg_00, gp_reg_00);
            app.label_gp_reg_01.Text = sprintf('01  0x%04X  (%05d)', gp_reg_01, gp_reg_01);
            app.label_gp_reg_02.Text = sprintf('02  0x%04X  (%05d)', gp_reg_02, gp_reg_02);
            app.label_gp_reg_03.Text = sprintf('03  0x%04X  (%05d)', gp_reg_03, gp_reg_03);
            app.label_gp_reg_04.Text = sprintf('04  0x%04X  (%05d)', gp_reg_04, gp_reg_04);
            app.label_gp_reg_05.Text = sprintf('05  0x%04X  (%05d)', gp_reg_05, gp_reg_05);
            app.label_gp_reg_06.Text = sprintf('06  0x%04X  (%05d)', gp_reg_06, gp_reg_06);
            app.label_gp_reg_07.Text = sprintf('07  0x%04X  (%05d)', gp_reg_07, gp_reg_07);
            app.label_gp_reg_08.Text = sprintf('08  0x%04X  (%05d)', gp_reg_08, gp_reg_08);
            app.label_gp_reg_09.Text = sprintf('09  0x%04X  (%05d)', gp_reg_09, gp_reg_09);
            app.label_gp_reg_10.Text = sprintf('10  0x%04X  (%05d)', gp_reg_10, gp_reg_10);
            app.label_gp_reg_11.Text = sprintf('11  0x%04X  (%05d)', gp_reg_11, gp_reg_11);
            app.label_gp_reg_12.Text = sprintf('12  0x%04X  (%05d)', gp_reg_12, gp_reg_12);
            app.label_gp_reg_13.Text = sprintf('13  0x%04X  (%05d)', gp_reg_13, gp_reg_13);
            app.label_gp_reg_14.Text = sprintf('14  0x%04X  (%05d)', gp_reg_14, gp_reg_14);
            app.label_gp_reg_15.Text = sprintf('15  0x%04X  (%05d)', gp_reg_15, gp_reg_15);

            stack_00 = evalin('base', 'stack_00');
            stack_01 = evalin('base', 'stack_01');
            stack_02 = evalin('base', 'stack_02');
            stack_03 = evalin('base', 'stack_03');
            stack_04 = evalin('base', 'stack_04');
            stack_05 = evalin('base', 'stack_05');
            stack_06 = evalin('base', 'stack_06');
            stack_07 = evalin('base', 'stack_07');
            stack_08 = evalin('base', 'stack_08');
            stack_09 = evalin('base', 'stack_09');
            stack_10 = evalin('base', 'stack_10');
            stack_11 = evalin('base', 'stack_11');
            stack_12 = evalin('base', 'stack_12');
            stack_13 = evalin('base', 'stack_13');
            stack_14 = evalin('base', 'stack_14');
            stack_15 = evalin('base', 'stack_15');
            
            app.label_stack_00.Text = sprintf('00  0x%04X  (%05d)', stack_00, stack_00);
            app.label_stack_01.Text = sprintf('01  0x%04X  (%05d)', stack_01, stack_01);
            app.label_stack_02.Text = sprintf('02  0x%04X  (%05d)', stack_02, stack_02);
            app.label_stack_03.Text = sprintf('03  0x%04X  (%05d)', stack_03, stack_03);
            app.label_stack_04.Text = sprintf('04  0x%04X  (%05d)', stack_04, stack_04);
            app.label_stack_05.Text = sprintf('05  0x%04X  (%05d)', stack_05, stack_05);
            app.label_stack_06.Text = sprintf('06  0x%04X  (%05d)', stack_06, stack_06);
            app.label_stack_07.Text = sprintf('07  0x%04X  (%05d)', stack_07, stack_07);
            app.label_stack_08.Text = sprintf('08  0x%04X  (%05d)', stack_08, stack_08);
            app.label_stack_09.Text = sprintf('09  0x%04X  (%05d)', stack_09, stack_09);
            app.label_stack_10.Text = sprintf('10  0x%04X  (%05d)', stack_10, stack_10);
            app.label_stack_11.Text = sprintf('11  0x%04X  (%05d)', stack_11, stack_11);
            app.label_stack_12.Text = sprintf('12  0x%04X  (%05d)', stack_12, stack_12);
            app.label_stack_13.Text = sprintf('13  0x%04X  (%05d)', stack_13, stack_13);
            app.label_stack_14.Text = sprintf('14  0x%04X  (%05d)', stack_14, stack_14);
            app.label_stack_15.Text = sprintf('15  0x%04X  (%05d)', stack_15, stack_15);

            app.label_stack_00.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_01.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_02.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_03.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_04.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_05.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_06.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_07.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_08.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_09.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_10.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_11.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_12.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_13.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_14.BackgroundColor = [0.8863 0.8863 0.8863];
            app.label_stack_15.BackgroundColor = [0.8863 0.8863 0.8863];
             
            switch (stack_pointer)
                case 0,  app.label_stack_00.BackgroundColor = [1 1 1];
                case 1,  app.label_stack_01.BackgroundColor = [1 1 1];
                case 2,  app.label_stack_02.BackgroundColor = [1 1 1];
                case 3,  app.label_stack_03.BackgroundColor = [1 1 1];
                case 4,  app.label_stack_04.BackgroundColor = [1 1 1];
                case 5,  app.label_stack_05.BackgroundColor = [1 1 1];
                case 6,  app.label_stack_06.BackgroundColor = [1 1 1];
                case 7,  app.label_stack_07.BackgroundColor = [1 1 1];
                case 8,  app.label_stack_08.BackgroundColor = [1 1 1];
                case 9,  app.label_stack_09.BackgroundColor = [1 1 1];
                case 10, app.label_stack_10.BackgroundColor = [1 1 1];
                case 11, app.label_stack_11.BackgroundColor = [1 1 1];
                case 12, app.label_stack_12.BackgroundColor = [1 1 1];
                case 13, app.label_stack_13.BackgroundColor = [1 1 1];
                case 14, app.label_stack_14.BackgroundColor = [1 1 1];
                case 15, app.label_stack_15.BackgroundColor = [1 1 1];
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Instructions
            app.inst = c;

            Definitions
            app.def = c;

            app.t = timer();
            app.t.TimerFcn      = @app.UpdateOutputCb;
            app.t.ExecutionMode = 'fixedDelay';
            app.t.Period        = 0.2;

            if (1 == strcmp('off', app.t.Running))
                start(app.t);
            end
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            stop(app.t);
            delete(app.t);
            delete(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Mrkvicka9000 (Front Panel)';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.label_gp_reg_00 = uilabel(app.UIFigure);
            app.label_gp_reg_00.Text = 'N/A';
            app.label_gp_reg_01 = uilabel(app.UIFigure);
            app.label_gp_reg_01.Text = 'N/A';
            app.label_gp_reg_02 = uilabel(app.UIFigure);
            app.label_gp_reg_02.Text = 'N/A';
            app.label_gp_reg_03 = uilabel(app.UIFigure);
            app.label_gp_reg_03.Text = 'N/A';
            app.label_gp_reg_04 = uilabel(app.UIFigure);
            app.label_gp_reg_04.Text = 'N/A';
            app.label_gp_reg_05 = uilabel(app.UIFigure);
            app.label_gp_reg_05.Text = 'N/A';
            app.label_gp_reg_06 = uilabel(app.UIFigure);
            app.label_gp_reg_06.Text = 'N/A';
            app.label_gp_reg_07 = uilabel(app.UIFigure);
            app.label_gp_reg_07.Text = 'N/A';
            app.label_gp_reg_08 = uilabel(app.UIFigure);
            app.label_gp_reg_08.Text = 'N/A';
            app.label_gp_reg_09 = uilabel(app.UIFigure);
            app.label_gp_reg_09.Text = 'N/A';
            app.label_gp_reg_10 = uilabel(app.UIFigure);
            app.label_gp_reg_10.Text = 'N/A';
            app.label_gp_reg_11 = uilabel(app.UIFigure);
            app.label_gp_reg_11.Text = 'N/A';
            app.label_gp_reg_12 = uilabel(app.UIFigure);
            app.label_gp_reg_12.Text = 'N/A';
            app.label_gp_reg_13 = uilabel(app.UIFigure);
            app.label_gp_reg_13.Text = 'N/A';
            app.label_gp_reg_14 = uilabel(app.UIFigure);
            app.label_gp_reg_14.Text = 'N/A';
            app.label_gp_reg_15 = uilabel(app.UIFigure);
            app.label_gp_reg_15.Text = 'N/A';
            app.label_program_counter = uilabel(app.UIFigure);
            app.label_program_counter.Text = 'Program Counter   ';
            app.label_instruction = uilabel(app.UIFigure);
            app.label_instruction.Text = 'Instruction   ';
            app.label_return_address = uilabel(app.UIFigure);
            app.label_return_address.Text = 'Return Address   ';
            app.label_stack_pointer = uilabel(app.UIFigure);
            app.label_stack_pointer.Text = 'Stack Pointer   ';
            app.label_keyboard = uilabel(app.UIFigure);
            app.label_keyboard.Text = 'Keyboard   ';
            app.label_status_register = uilabel(app.UIFigure);
            app.label_status_register.Text = 'Status Register   ';
            app.label_random_number = uilabel(app.UIFigure);
            app.label_random_number.Text = 'Random Number   ';
            app.label_program_counter_value = uilabel(app.UIFigure);
            app.label_program_counter_value.Text = 'N/A';
            app.label_instruction_value = uilabel(app.UIFigure);
            app.label_instruction_value.Text = 'N/A';
            app.label_stack_pointer_value = uilabel(app.UIFigure);
            app.label_stack_pointer_value.Text = 'N/A';
            app.label_return_address_value = uilabel(app.UIFigure);
            app.label_return_address_value.Text = 'N/A';
            app.label_status_register_value = uilabel(app.UIFigure);
            app.label_status_register_value.Text = 'N/A';
            app.label_keyboard_value = uilabel(app.UIFigure);
            app.label_keyboard_value.Text = 'N/A';
            app.label_random_number_value = uilabel(app.UIFigure);
            app.label_random_number_value.Text = 'N/A';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'General Purpose Registers';
            app.label_stack_00 = uilabel(app.UIFigure);
            app.label_stack_00.Text = 'N/A';
            app.label_stack_01 = uilabel(app.UIFigure);
            app.label_stack_01.Text = 'N/A';
            app.label_stack_02 = uilabel(app.UIFigure);
            app.label_stack_02.Text = 'N/A';
            app.label_stack_03 = uilabel(app.UIFigure);
            app.label_stack_03.Text = 'N/A';
            app.label_stack_04 = uilabel(app.UIFigure);
            app.label_stack_04.Text = 'N/A';
            app.label_stack_05 = uilabel(app.UIFigure);
            app.label_stack_05.Text = 'N/A';
            app.label_stack_06 = uilabel(app.UIFigure);
            app.label_stack_06.Text = 'N/A';
            app.label_stack_07 = uilabel(app.UIFigure);
            app.label_stack_07.Text = 'N/A';
            app.label_stack_08 = uilabel(app.UIFigure);
            app.label_stack_08.Text = 'N/A';
            app.label_stack_09 = uilabel(app.UIFigure);
            app.label_stack_09.Text = 'N/A';
            app.label_stack_10 = uilabel(app.UIFigure);
            app.label_stack_10.Text = 'N/A';
            app.label_stack_11 = uilabel(app.UIFigure);
            app.label_stack_11.Text = 'N/A';
            app.label_stack_12 = uilabel(app.UIFigure);
            app.label_stack_12.Text = 'N/A';
            app.label_stack_13 = uilabel(app.UIFigure);
            app.label_stack_13.Text = 'N/A';
            app.label_stack_14 = uilabel(app.UIFigure);
            app.label_stack_14.Text = 'N/A';
            app.label_stack_15 = uilabel(app.UIFigure);
            app.label_stack_15.Text = 'N/A';
            app.stack = uilabel(app.UIFigure);
            app.stack.Text = 'Stack';
            app.label_opcode_value = uilabel(app.UIFigure);
            app.label_opcode_value.Text = 'N/A';
            app.label_dest_reg_value = uilabel(app.UIFigure);
            app.label_dest_reg_value.Text = 'N/A';
            app.label_src_reg_value = uilabel(app.UIFigure);
            app.label_src_reg_value.Text = 'N/A';
            app.label_immediate_value = uilabel(app.UIFigure);
            app.label_immediate_value.Text = 'N/A';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = '      Op      Dest   Src       Imm ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FrontPanel()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end