classdef DataMemoryPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure        % Mrkvicka9000 (Data Me...
        Label4              matlab.ui.control.Label
        Label               matlab.ui.control.Label % N/A  N/A  N/A  N/A  N...
        Label2              matlab.ui.control.Label % 0x0000
        Label3              matlab.ui.control.Label % 0x00 0x01 0x02 0x03 0...
        Label5              matlab.ui.control.Label
        Label6              matlab.ui.control.Label % Data Memory
        label_selected_background matlab.ui.control.Label
        label_selected_text matlab.ui.control.Label % N/A
    end


    properties (Access = private)
        t     % Timer
        inst  % Instruction
        def   % Definition
        
        color_selected   = [0.46 0.75 0.95];
        color_unselected = 'red';
    
        OFFSET_X = 63;
        OFFSET_Y = 890;
        WIDTH    = 35;
        HEIGHT   = 14;
        MAX_X    = 16;
        
        instruction;
        offset_register;
        dest_reg;
        src_reg;
        immediate;
        gp_reg;
        DataBus_0_255;
        DataBus_256_511;
        DataBus_512_767;
        DataBus_768_1023;
    end

    
    methods (Access = private)
        function UpdateOutputCb(app, obj, event)
            addr = 0;
            ReadDataFromWorkspace(app);
            DeletePointer(app);
            
            j = 0;
            % Print memory content.
            for (i = 1 : 16 : 256)
                j = j + 1;
                app.Label.Text{j} = sprintf('0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X', ...
                    app.DataBus_0_255(i +  0), ...
                    app.DataBus_0_255(i +  1), ...
                    app.DataBus_0_255(i +  2), ...
                    app.DataBus_0_255(i +  3), ...
                    app.DataBus_0_255(i +  4), ...
                    app.DataBus_0_255(i +  5), ...
                    app.DataBus_0_255(i +  6), ...
                    app.DataBus_0_255(i +  7), ...
                    app.DataBus_0_255(i +  8), ...
                    app.DataBus_0_255(i +  9), ...
                    app.DataBus_0_255(i + 10), ...
                    app.DataBus_0_255(i + 11), ...
                    app.DataBus_0_255(i + 12), ...
                    app.DataBus_0_255(i + 13), ...
                    app.DataBus_0_255(i + 14), ...
                    app.DataBus_0_255(i + 15));
            end

            % Print memory content.
            for (i = 1 : 16 : 256)
                j = j + 1;
                app.Label.Text{j} = sprintf('0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X', ...
                    app.DataBus_256_511(i +  0), ...
                    app.DataBus_256_511(i +  1), ...
                    app.DataBus_256_511(i +  2), ...
                    app.DataBus_256_511(i +  3), ...
                    app.DataBus_256_511(i +  4), ...
                    app.DataBus_256_511(i +  5), ...
                    app.DataBus_256_511(i +  6), ...
                    app.DataBus_256_511(i +  7), ...
                    app.DataBus_256_511(i +  8), ...
                    app.DataBus_256_511(i +  9), ...
                    app.DataBus_256_511(i + 10), ...
                    app.DataBus_256_511(i + 11), ...
                    app.DataBus_256_511(i + 12), ...
                    app.DataBus_256_511(i + 13), ...
                    app.DataBus_256_511(i + 14), ...
                    app.DataBus_256_511(i + 15));
            end

            % Print memory content.
            for (i = 1 : 16 : 256)
                j = j + 1;
                app.Label.Text{j} = sprintf('0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X', ...
                    app.DataBus_512_767(i +  0), ...
                    app.DataBus_512_767(i +  1), ...
                    app.DataBus_512_767(i +  2), ...
                    app.DataBus_512_767(i +  3), ...
                    app.DataBus_512_767(i +  4), ...
                    app.DataBus_512_767(i +  5), ...
                    app.DataBus_512_767(i +  6), ...
                    app.DataBus_512_767(i +  7), ...
                    app.DataBus_512_767(i +  8), ...
                    app.DataBus_512_767(i +  9), ...
                    app.DataBus_512_767(i + 10), ...
                    app.DataBus_512_767(i + 11), ...
                    app.DataBus_512_767(i + 12), ...
                    app.DataBus_512_767(i + 13), ...
                    app.DataBus_512_767(i + 14), ...
                    app.DataBus_512_767(i + 15));
            end

            % Print memory content.
            for (i = 1 : 16 : 256)
                j = j + 1;
                app.Label.Text{j} = sprintf('0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X 0x%02X', ...
                    app.DataBus_768_1023(i +  0), ...
                    app.DataBus_768_1023(i +  1), ...
                    app.DataBus_768_1023(i +  2), ...
                    app.DataBus_768_1023(i +  3), ...
                    app.DataBus_768_1023(i +  4), ...
                    app.DataBus_768_1023(i +  5), ...
                    app.DataBus_768_1023(i +  6), ...
                    app.DataBus_768_1023(i +  7), ...
                    app.DataBus_768_1023(i +  8), ...
                    app.DataBus_768_1023(i +  9), ...
                    app.DataBus_768_1023(i + 10), ...
                    app.DataBus_768_1023(i + 11), ...
                    app.DataBus_768_1023(i + 12), ...
                    app.DataBus_768_1023(i + 13), ...
                    app.DataBus_768_1023(i + 14), ...
                    app.DataBus_768_1023(i + 15));
            end

            % Decode instruction
            instr_msb = bitshift(app.instruction, -8);
            switch (bitand(instr_msb, app.def.INSTR_FORMAT_MASK))
        	case app.def.INSTR_FORMAT_0
                    % Instructions of format 0 do not operate with data memory.
                    
            	case app.def.INSTR_FORMAT_1
		    switch (bitor(bitand(instr_msb, app.def.FORMAT_1_OPCODE_MASK), app.def.INSTR_FORMAT_1))
                        case {app.inst.STLI, app.inst.STUI}
                            addr = app.offset_register + app.immediate;
                            DrawPointer(app, addr);

                        otherwise
                            % Do nothing.
                    end

		case app.def.INSTR_FORMAT_2
  		    switch (bitor(bitand(instr_msb, app.def.FORMAT_2_OPCODE_MASK), app.def.INSTR_FORMAT_2))
                        case {app.inst.LDLI, app.inst.LDUI},
                            addr = app.offset_register + app.immediate;
                            DrawPointer(app, addr);

                        otherwise
                            % Do nothing.
                    end

		case app.def.INSTR_FORMAT_3
                    switch (bitor(bitand(instr_msb, app.def.FORMAT_3_OPCODE_MASK), app.def.INSTR_FORMAT_3))
                        case {app.inst.LDL, app.inst.LDU}
                            addr = app.gp_reg(app.src_reg + 1);
                            DrawPointer(app, addr);

                        case {app.inst.STL, app.inst.STU}
                            addr = app.gp_reg(app.dest_reg + 1);
                            DrawPointer(app, addr);
                            
                        otherwise
                            % Do nothing.
                    end

                otherwise
                    % Do nothing.
            end
        end

        
        function ReadDataFromWorkspace(app)
            app.instruction     = evalin('base', 'instruction');
            app.dest_reg        = evalin('base', 'dest_reg');
            app.src_reg         = evalin('base', 'src_reg');
            app.immediate       = evalin('base', 'immediate');
            app.offset_register = evalin('base', 'offset_register');
            
            % Read values from workspace.
            app.gp_reg =                        ...
            [                               ...
                evalin('base', 'gp_reg_00') ...
                evalin('base', 'gp_reg_01') ...
                evalin('base', 'gp_reg_02') ...
                evalin('base', 'gp_reg_03') ...
                evalin('base', 'gp_reg_04') ...
                evalin('base', 'gp_reg_05') ...
                evalin('base', 'gp_reg_06') ...
                evalin('base', 'gp_reg_07') ...
            ];
        
            % Read values from workspace.
            app.DataBus_0_255    = evalin('base', 'DataRom');
            app.DataBus_256_511  = evalin('base', 'DataBus_256_511');
            app.DataBus_512_767  = evalin('base', 'DataBus_512_767');
            app.DataBus_768_1023 = evalin('base', 'DataBus_768_1023');
        end
        
        
        function DrawPointer(app, addr)
            y = idivide(uint16(addr) - 0, uint16(app.MAX_X)) + 1;
            x =     mod(uint16(addr) - 0, uint16(app.MAX_X)) + 1;

            x = app.OFFSET_X + (x - 1)*app.WIDTH;
            y = app.OFFSET_Y - (y - 1)*app.HEIGHT;

            % Draw selection box (background).
            app.label_selected_background.Position = [x y app.WIDTH app.HEIGHT - 2];
            app.label_selected_background.BackgroundColor = app.color_selected;
            
            % Draw selected text.
            app.label_selected_text.Position = [x-1 y+1 app.WIDTH app.HEIGHT];
            if ((addr >= 0) && (addr <= 255))
                i = addr + 1;
                app.label_selected_text.Text = sprintf('0x%02X', app.DataBus_0_255(i));
            elseif ((addr >= 256) && (addr <= 511))
                i = addr - 255;
                app.label_selected_text.Text = sprintf('0x%02X', app.DataBus_256_511(i));
            elseif ((addr >= 512) && (addr <= 767))
                i = addr - 511;
                app.label_selected_text.Text = sprintf('0x%02X', app.DataBus_512_767(i));
            elseif ((addr >= 768) && (addr <= 1023))
                i = addr - 767;
                app.label_selected_text.Text = sprintf('0x%02X', app.DataBus_768_1023(i));
            end
        end

        
        function DeletePointer(app)
            app.label_selected_background.BackgroundColor = 'none';
            app.label_selected_text.Text = '';
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if (0 == bdIsLoaded('Computer'))
                fprintf('\nFrontPanel error: The Computer model is not started!!\n');
                delete(app.UIFigure);

            elseif (0 == strcmp('running', get_param('Computer','SimulationStatus')))  && ...
                   (0 == strcmp('paused',  get_param('Computer','SimulationStatus'))) 
                fprintf('\nFrontPanel error: The Computer model is not started!!\n');
                delete(app.UIFigure);

            else
                Instructions
                app.inst = c;
    
                Definitions
                app.def = c;

                app.UIFigure.Resize = 'off';
                app.UIFigure.OuterPosition = [400 102 630 946];
                app.UIFigure.Color  = 'black';
                
                % Init pointer.
                app.label_selected_text.FontColor           = 'white';
                app.label_selected_text.BackgroundColor     = 'none';
                app.label_selected_text.FontName            = 'Courier';
                app.label_selected_text.HorizontalAlignment = 'center';
                app.label_selected_text.VerticalAlignment   = 'center';
                app.label_selected_text.Text                = '';
% 
%                 app.label_selected_background.Text            = '';

                app.t = timer();
                app.t.TimerFcn      = @app.UpdateOutputCb;
                app.t.ExecutionMode = 'fixedDelay';
                app.t.Period        = 0.5;
    
                if (1 == strcmp('off', app.t.Running))
                    start(app.t);
                end

            end
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            stop(app.t);
            delete(app.t);
            delete(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Mrkvicka9000 (Data Memory Panel)';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A'; 'N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A'; 'N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A'; 'N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A'; 'N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A'; 'N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A'; 'N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A'; 'N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A  N/A'};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
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = {'0x0000'; '0x0010'; '0x0020'; '0x0030'; '0x0040'; '0x0050'; '0x0060'; '0x0070'; '0x0080'; '0x0090'; '0x00A0'; '0x00B0'; '0x00C0'; '0x00D0'; '0x00E0'; '0x00F0'; '0x0100'; '0x0110'; '0x0120'; '0x0130'; '0x0140'; '0x0150'; '0x0160'; '0x0170'; '0x0180'; '0x0190'; '0x01A0'; '0x01B0'; '0x01C0'; '0x01D0'; '0x01E0'; '0x01F0'; '0x0200'; '0x0210'; '0x0220'; '0x0230'; '0x0240'; '0x0250'; '0x0260'; '0x0270'; '0x0280'; '0x0290'; '0x02A0'; '0x02B0'; '0x02C0'; '0x02D0'; '0x02E0'; '0x02F0'; '0x0300'; '0x0310'; '0x0320'; '0x0330'; '0x0340'; '0x0350'; '0x0360'; '0x0370'; '0x0380'; '0x0390'; '0x03A0'; '0x03B0'; '0x03C0'; '0x03D0'; '0x03E0'; '0x03F0'};
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = '0x00 0x01 0x02 0x03 0x04 0x05 0x06 0x07 0x08 0x09 0x0A 0x0B 0x0C 0x0D 0x0E 0x0F';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = '';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Data Memory';
            app.label_selected_background = uilabel(app.UIFigure);
            app.label_selected_background.Text = '';
            app.label_selected_text = uilabel(app.UIFigure);
            app.label_selected_text.Text = 'N/A';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataMemoryPanel()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end