classdef FrontPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure        % Mrkvicka9000 (Fro...
        label_frame_mrkvicka9000 matlab.ui.control.Label
        Label4                  matlab.ui.control.Label %  9000 
        Label7                  matlab.ui.control.Label %  MRKVIČKA 
        Label8                  matlab.ui.control.Label % m
        Label9                  matlab.ui.control.Label % r
        Label10                 matlab.ui.control.Label % k
        Label11                 matlab.ui.control.Label % v
        Label12                 matlab.ui.control.Label % i
        Label13                 matlab.ui.control.Label % č
        Label14                 matlab.ui.control.Label % k
        Label15                 matlab.ui.control.Label % a
        Label16                 matlab.ui.control.Label % m
        Label17                 matlab.ui.control.Label % r
        Label18                 matlab.ui.control.Label % k
        Label19                 matlab.ui.control.Label % v
        Label20                 matlab.ui.control.Label % i
        Label21                 matlab.ui.control.Label % č
        Label22                 matlab.ui.control.Label % k
        Label23                 matlab.ui.control.Label % a
        Label24                 matlab.ui.control.Label % Jožko Mrkvička Co...
        label_frame_2           matlab.ui.control.Label
        label_program_counter   matlab.ui.control.Label % Program Counter   
        label_instruction       matlab.ui.control.Label % Instruction   
        label_return_address    matlab.ui.control.Label % Return Address   
        label_stack_pointer     matlab.ui.control.Label % Stack Pointer   
        label_keyboard          matlab.ui.control.Label % Keyboard   
        label_status_register   matlab.ui.control.Label % Status Register   
        label_random_number     matlab.ui.control.Label % Random Number   
        label_program_counter_value matlab.ui.control.Label % N/A
        label_instruction_value matlab.ui.control.Label % N/A
        label_stack_pointer_value matlab.ui.control.Label % N/A
        label_return_address_value matlab.ui.control.Label % N/A
        label_status_register_value matlab.ui.control.Label % N/A
        label_keyboard_value    matlab.ui.control.Label % N/A
        label_random_number_value matlab.ui.control.Label % N/A
        label_frame_3           matlab.ui.control.Label
        label_gp_reg_00         matlab.ui.control.Label % N/A
        label_gp_reg_01         matlab.ui.control.Label % N/A
        label_gp_reg_02         matlab.ui.control.Label % N/A
        label_gp_reg_03         matlab.ui.control.Label % N/A
        label_gp_reg_04         matlab.ui.control.Label % N/A
        label_gp_reg_05         matlab.ui.control.Label % N/A
        label_gp_reg_06         matlab.ui.control.Label % N/A
        label_gp_reg_07         matlab.ui.control.Label % N/A
        label_gp_reg_08         matlab.ui.control.Label % N/A
        label_gp_reg_09         matlab.ui.control.Label % N/A
        label_gp_reg_10         matlab.ui.control.Label % N/A
        label_gp_reg_11         matlab.ui.control.Label % N/A
        label_gp_reg_12         matlab.ui.control.Label % N/A
        label_gp_reg_13         matlab.ui.control.Label % N/A
        label_gp_reg_14         matlab.ui.control.Label % N/A
        label_gp_reg_15         matlab.ui.control.Label % N/A
        Label                   matlab.ui.control.Label % General Purpose R...
        label_frame_4           matlab.ui.control.Label
        label_stack_00          matlab.ui.control.Label % N/A
        label_stack_01          matlab.ui.control.Label % N/A
        label_stack_02          matlab.ui.control.Label % N/A
        label_stack_03          matlab.ui.control.Label % N/A
        label_stack_04          matlab.ui.control.Label % N/A
        label_stack_05          matlab.ui.control.Label % N/A
        label_stack_06          matlab.ui.control.Label % N/A
        label_stack_07          matlab.ui.control.Label % N/A
        label_stack_08          matlab.ui.control.Label % N/A
        label_stack_09          matlab.ui.control.Label % N/A
        label_stack_10          matlab.ui.control.Label % N/A
        label_stack_11          matlab.ui.control.Label % N/A
        label_stack_12          matlab.ui.control.Label % N/A
        label_stack_13          matlab.ui.control.Label % N/A
        label_stack_14          matlab.ui.control.Label % N/A
        label_stack_15          matlab.ui.control.Label % N/A
        stack                   matlab.ui.control.Label % Stack
        Label25                 matlab.ui.control.Label
        label_opcode_value      matlab.ui.control.Label % N/A
        label_dest_reg_value    matlab.ui.control.Label % N/A
        label_src_reg_value     matlab.ui.control.Label % N/A
        label_immediate_value   matlab.ui.control.Label % N/A
        Label2                  matlab.ui.control.Label %       Op         ...
        Label28                 matlab.ui.control.Label % r00
        Label29                 matlab.ui.control.Label % r01
        Label30                 matlab.ui.control.Label % r02
        Label31                 matlab.ui.control.Label % r03
        Label32                 matlab.ui.control.Label % r04
        Label33                 matlab.ui.control.Label % r05
        Label34                 matlab.ui.control.Label % r06
        Label35                 matlab.ui.control.Label % r07
        Label36                 matlab.ui.control.Label % r08
        Label37                 matlab.ui.control.Label % r09
        Label38                 matlab.ui.control.Label % r10
        Label39                 matlab.ui.control.Label % r11
        Label40                 matlab.ui.control.Label % r12
        Label41                 matlab.ui.control.Label % r13
        Label42                 matlab.ui.control.Label % r14
        Label43                 matlab.ui.control.Label % r15
        Label44                 matlab.ui.control.Label % 00
        Label45                 matlab.ui.control.Label % 01
        Label46                 matlab.ui.control.Label % 02
        Label47                 matlab.ui.control.Label % 03
        Label48                 matlab.ui.control.Label % 04
        Label49                 matlab.ui.control.Label % 05
        Label50                 matlab.ui.control.Label % 06
        Label51                 matlab.ui.control.Label % 07
        Label52                 matlab.ui.control.Label % 08
        Label53                 matlab.ui.control.Label % 09
        Label54                 matlab.ui.control.Label % 10
        Label55                 matlab.ui.control.Label % 11
        Label56                 matlab.ui.control.Label % 12
        Label57                 matlab.ui.control.Label % 13
        Label58                 matlab.ui.control.Label % 14
        Label59                 matlab.ui.control.Label % 15
    end

    properties (Access = private)
        t             % Timer
        inst          % Instruction
        def           % Definition
        label_gp_reg
        label_stack
    end

    methods (Access = public)
        function UpdateOutputCb(app, obj, event)
            color_selected   = [0.46 0.75 0.95];
            color_unselected = 'red';

            % Read values from workspace.
            program_counter = evalin('base', 'program_counter');
            instruction     = evalin('base', 'instruction');
            stack_pointer   = evalin('base', 'stack_pointer');
            return_address  = evalin('base', 'return_address');
            status_register = evalin('base', 'status_register');
            keyboard        = evalin('base', 'keyboard');
            random_number   = evalin('base', 'random_number');

            % Read values from workspace.
            dest_reg        = evalin('base', 'dest_reg');
            src_reg         = evalin('base', 'src_reg');
            immediate       = evalin('base', 'immediate');

            % Read values from workspace.
            gp_reg =                        ...
            [                               ...
                evalin('base', 'gp_reg_00') ...
                evalin('base', 'gp_reg_01') ...
                evalin('base', 'gp_reg_02') ...
                evalin('base', 'gp_reg_03') ...
                evalin('base', 'gp_reg_04') ...
                evalin('base', 'gp_reg_05') ...
                evalin('base', 'gp_reg_06') ...
                evalin('base', 'gp_reg_07') ...
                evalin('base', 'gp_reg_08') ...
                evalin('base', 'gp_reg_09') ...
                evalin('base', 'gp_reg_10') ...
                evalin('base', 'gp_reg_11') ...
                evalin('base', 'gp_reg_12') ...
                evalin('base', 'gp_reg_13') ...
                evalin('base', 'gp_reg_14') ...
                evalin('base', 'gp_reg_15') ...
            ];
            
            % Read values from workspace.
            main_stack =                   ...
            [                              ...
                evalin('base', 'stack_00') ...
                evalin('base', 'stack_01') ...
                evalin('base', 'stack_02') ...
                evalin('base', 'stack_03') ...
                evalin('base', 'stack_04') ...
                evalin('base', 'stack_05') ...
                evalin('base', 'stack_06') ...
                evalin('base', 'stack_07') ...
                evalin('base', 'stack_08') ...
                evalin('base', 'stack_09') ...
                evalin('base', 'stack_10') ...
                evalin('base', 'stack_11') ...
                evalin('base', 'stack_12') ...
                evalin('base', 'stack_13') ...
                evalin('base', 'stack_14') ...
                evalin('base', 'stack_15') ...
            ];
        
            % Print registers.
            app.label_program_counter_value.Text = sprintf('0x%04X  (%05d)', program_counter, program_counter);
            app.label_instruction_value.Text     = sprintf('0x%04X  (%05d)', instruction,     instruction);
            app.label_stack_pointer_value.Text   = sprintf('0x%04X  (%05d)', stack_pointer,   stack_pointer);
            app.label_return_address_value.Text  = sprintf('0x%04X  (%05d)', return_address,  return_address);
            app.label_status_register_value.Text = sprintf('0x%04X  (%05d)', status_register, status_register);
            app.label_keyboard_value.Text        = sprintf('0x%04X  (%05d)', keyboard,        keyboard);
            app.label_random_number_value.Text   = sprintf('0x%04X  (%05d)', random_number,   random_number);

            % Decode instruction and print opcode, destination register, source register and immediate value.
            instr_msb = bitshift(instruction, -8);
            switch (bitand(instr_msb, app.def.INSTR_FORMAT_MASK))
        	case app.def.INSTR_FORMAT_0
		    switch (bitor(bitand(instr_msb, app.def.FORMAT_0_OPCODE_MASK), app.def.INSTR_FORMAT_0))
                        case app.inst.CALL,      app.label_opcode_value.Text = 'CALL'; 
                        case app.inst.JMP,       app.label_opcode_value.Text = 'JMP';
                        case app.inst.JPE,       app.label_opcode_value.Text = 'JPE';
                        case app.inst.JLT,       app.label_opcode_value.Text = 'JLT';
                        otherwise                app.label_opcode_value.Text = 'N/A';
                    end
                    app.label_dest_reg_value.Text  = 'N/A';
                    app.label_src_reg_value.Text   = 'N/A';
                    app.label_immediate_value.Text = sprintf('0x%04X', immediate);
                    
            	case app.def.INSTR_FORMAT_1
		    switch (bitor(bitand(instr_msb, app.def.FORMAT_1_OPCODE_MASK), app.def.INSTR_FORMAT_1))
                        case app.inst.CMPI,      app.label_opcode_value.Text = 'CMPI';
                        case app.inst.STOREI,    app.label_opcode_value.Text = 'STOREI';
                        case app.inst.SHIFTI,    app.label_opcode_value.Text = 'SHIFTI';
                        otherwise                app.label_opcode_value.Text = 'N/A';
                    end
                    app.label_dest_reg_value.Text  = 'N/A';
                    app.label_src_reg_value.Text   = sprintf('r%d', src_reg);
                    app.label_immediate_value.Text = sprintf('0x%04X', immediate);

		case app.def.INSTR_FORMAT_2
  		    switch (bitor(bitand(instr_msb, app.def.FORMAT_2_OPCODE_MASK), app.def.INSTR_FORMAT_2))
                        case app.inst.ADDI,      app.label_opcode_value.Text = 'ADDI';
                        case app.inst.LOADI,     app.label_opcode_value.Text = 'LOADI';
                        case app.inst.MOVU,      app.label_opcode_value.Text = 'MOVU';
                        case app.inst.MOVL,      app.label_opcode_value.Text = 'MOVL';
                        otherwise                app.label_opcode_value.Text = 'N/A';
                    end
                    app.label_dest_reg_value.Text  = sprintf('r%d', dest_reg);
                    app.label_src_reg_value.Text   = 'N/A';
                    app.label_immediate_value.Text = sprintf('0x%04X', immediate);

		case app.def.INSTR_FORMAT_3
                    app.label_dest_reg_value.Text  = sprintf('r%d', dest_reg);
                    app.label_src_reg_value.Text   = sprintf('r%d', src_reg);
                    app.label_immediate_value.Text = 'N/A';

                    switch (bitor(bitand(instr_msb, app.def.FORMAT_3_OPCODE_MASK), app.def.INSTR_FORMAT_3))
                        case app.inst.NOT,       app.label_opcode_value.Text = 'NOT';     app.label_dest_reg_value.Text = 'N/A';
                        case app.inst.XOR,       app.label_opcode_value.Text = 'XOR';
                        case app.inst.OR,        app.label_opcode_value.Text = 'OR';
                        case app.inst.AND,       app.label_opcode_value.Text = 'AND';
                        case app.inst.LOADL,     app.label_opcode_value.Text = 'LOADL';   app.label_src_reg_value.Text  = sprintf('m(r%d)', src_reg);
                        case app.inst.LOADU,     app.label_opcode_value.Text = 'LOADU';   app.label_src_reg_value.Text  = sprintf('m(r%d)', src_reg);
                        case app.inst.CMP,       app.label_opcode_value.Text = 'CMP';
                        case app.inst.RET,       app.label_opcode_value.Text = 'RET';     app.label_dest_reg_value.Text = 'N/A';   app.label_src_reg_value.Text = 'N/A';
                        case app.inst.POP,       app.label_opcode_value.Text = 'POP';     app.label_src_reg_value.Text = 'N/A';
                        case app.inst.PUSH,      app.label_opcode_value.Text = 'PUSH';    app.label_dest_reg_value.Text  = 'N/A';
                        case app.inst.SHIFT,     app.label_opcode_value.Text = 'SHIFT';
                        case app.inst.ADD,       app.label_opcode_value.Text = 'ADD';
                        case app.inst.NOT_USED,  app.label_opcode_value.Text = 'NOT_USED';
                        case app.inst.STOREL,    app.label_opcode_value.Text = 'STOREL';  app.label_dest_reg_value.Text  = sprintf('m(r%d)', dest_reg);
                        case app.inst.STOREU,    app.label_opcode_value.Text = 'STOREU';  app.label_dest_reg_value.Text  = sprintf('m(r%d)', dest_reg);
                        case app.inst.STORE,     app.label_opcode_value.Text = 'STORE';   app.label_dest_reg_value.Text  = sprintf('m(r%d)', dest_reg);
                        case app.inst.LOAD,      app.label_opcode_value.Text = 'LOAD';    app.label_src_reg_value.Text   = sprintf('m(r%d)', src_reg);
                        case app.inst.MOV,       app.label_opcode_value.Text = 'MOV';
                        otherwise                app.label_opcode_value.Text = 'N/A';
                    end

                otherwise
                   app.label_opcode_value.Text    = 'N/A';
                   app.label_dest_reg_value.Text  = 'N/A';
                   app.label_src_reg_value.Text   = 'N/A';
                   app.label_immediate_value.Text = 'N/A';
            end
            
            for (i = 1 : 16)
                app.label_gp_reg(i).Text            = sprintf('0x%04X  (%05d)', gp_reg(i), gp_reg(i));
                app.label_gp_reg(i).BackgroundColor = color_unselected;
                app.label_stack(i).Text             = sprintf('0x%04X  (%05d)', main_stack(i), main_stack(i));
                app.label_stack(i).BackgroundColor  = color_unselected;
            end

            % Highlight destination register
            if (0 == strcmp('N/A', app.label_dest_reg_value.Text))
                app.label_gp_reg(dest_reg + 1).BackgroundColor = color_selected;
            end

            % Highlight source register
            if (0 == strcmp('N/A', app.label_src_reg_value.Text))
                app.label_gp_reg(src_reg + 1).BackgroundColor  = color_selected;
            end

            % Highlight stack
            if (app.def.INSTR_FORMAT_3 == bitand(instr_msb, app.def.INSTR_FORMAT_MASK)) && ...
               (app.inst.POP == bitor(bitand(instr_msb, app.def.FORMAT_3_OPCODE_MASK), app.def.INSTR_FORMAT_3))
                app.label_stack(stack_pointer + 0).BackgroundColor = color_selected;
            else
                app.label_stack(stack_pointer + 1).BackgroundColor = color_selected;
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % TODO: Add buttons: RESET, STEP, RUN/STOP
            
            if (0 == bdIsLoaded('Computer'))
                fprintf('\nFrontPanel error: The Computer model is not started!!\n');
                delete(app.UIFigure);

            elseif (0 == strcmp('running', get_param('Computer','SimulationStatus')))  && ...
                   (0 == strcmp('paused',  get_param('Computer','SimulationStatus'))) 
                fprintf('\nFrontPanel error: The Computer model is not started!!\n');
                delete(app.UIFigure);

            else
                Instructions
                app.inst = c;
    
                Definitions
                app.def = c;
    
                app.label_gp_reg =      ...
                [                       ...
                    app.label_gp_reg_00 ...
                    app.label_gp_reg_01 ...
                    app.label_gp_reg_02 ...
                    app.label_gp_reg_03 ...
                    app.label_gp_reg_04 ...
                    app.label_gp_reg_05 ...
                    app.label_gp_reg_06 ...
                    app.label_gp_reg_07 ...
                    app.label_gp_reg_08 ...
                    app.label_gp_reg_09 ...
                    app.label_gp_reg_10 ...
                    app.label_gp_reg_11 ...
                    app.label_gp_reg_12 ...
                    app.label_gp_reg_13 ...
                    app.label_gp_reg_14 ...
                    app.label_gp_reg_15 ...
                ];
    
                app.label_stack =      ...
                [                      ...
                    app.label_stack_00 ...
                    app.label_stack_01 ...
                    app.label_stack_02 ...
                    app.label_stack_03 ...
                    app.label_stack_04 ...
                    app.label_stack_05 ...
                    app.label_stack_06 ...
                    app.label_stack_07 ...
                    app.label_stack_08 ...
                    app.label_stack_09 ...
                    app.label_stack_10 ...
                    app.label_stack_11 ...
                    app.label_stack_12 ...
                    app.label_stack_13 ...
                    app.label_stack_14 ...
                    app.label_stack_15 ...
                ];

                app.UIFigure.Resize = 'off';
                app.UIFigure.OuterPosition = [70 560 324 488];
                app.UIFigure.Color  = 'black';
                
                app.t = timer();
                app.t.TimerFcn      = @app.UpdateOutputCb;
                app.t.ExecutionMode = 'fixedDelay';
                app.t.Period        = 0.5;
    
                if (1 == strcmp('off', app.t.Running))
                    start(app.t);
                end
            end
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            stop(app.t);
            delete(app.t);
            delete(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Mrkvicka9000 (Front Panel)';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.label_frame_mrkvicka9000 = uilabel(app.UIFigure);
            app.label_frame_mrkvicka9000.Text = '';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = ' 9000 ';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = ' MRKVIČKA ';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'm';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'r';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'k';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'v';
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Text = 'i';
            app.Label13 = uilabel(app.UIFigure);
            app.Label13.Text = 'č';
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Text = 'k';
            app.Label15 = uilabel(app.UIFigure);
            app.Label15.Text = 'a';
            app.Label16 = uilabel(app.UIFigure);
            app.Label16.Text = 'm';
            app.Label17 = uilabel(app.UIFigure);
            app.Label17.Text = 'r';
            app.Label18 = uilabel(app.UIFigure);
            app.Label18.Text = 'k';
            app.Label19 = uilabel(app.UIFigure);
            app.Label19.Text = 'v';
            app.Label20 = uilabel(app.UIFigure);
            app.Label20.Text = 'i';
            app.Label21 = uilabel(app.UIFigure);
            app.Label21.Text = 'č';
            app.Label22 = uilabel(app.UIFigure);
            app.Label22.Text = 'k';
            app.Label23 = uilabel(app.UIFigure);
            app.Label23.Text = 'a';
            app.Label24 = uilabel(app.UIFigure);
            app.Label24.Text = 'Jožko Mrkvička Corporation';
            app.label_frame_2 = uilabel(app.UIFigure);
            app.label_frame_2.Text = '';
            app.label_program_counter = uilabel(app.UIFigure);
            app.label_program_counter.Text = 'Program Counter   ';
            app.label_instruction = uilabel(app.UIFigure);
            app.label_instruction.Text = 'Instruction   ';
            app.label_return_address = uilabel(app.UIFigure);
            app.label_return_address.Text = 'Return Address   ';
            app.label_stack_pointer = uilabel(app.UIFigure);
            app.label_stack_pointer.Text = 'Stack Pointer   ';
            app.label_keyboard = uilabel(app.UIFigure);
            app.label_keyboard.Text = 'Keyboard   ';
            app.label_status_register = uilabel(app.UIFigure);
            app.label_status_register.Text = 'Status Register   ';
            app.label_random_number = uilabel(app.UIFigure);
            app.label_random_number.Text = 'Random Number   ';
            app.label_program_counter_value = uilabel(app.UIFigure);
            app.label_program_counter_value.Text = 'N/A';
            app.label_instruction_value = uilabel(app.UIFigure);
            app.label_instruction_value.Text = 'N/A';
            app.label_stack_pointer_value = uilabel(app.UIFigure);
            app.label_stack_pointer_value.Text = 'N/A';
            app.label_return_address_value = uilabel(app.UIFigure);
            app.label_return_address_value.Text = 'N/A';
            app.label_status_register_value = uilabel(app.UIFigure);
            app.label_status_register_value.Text = 'N/A';
            app.label_keyboard_value = uilabel(app.UIFigure);
            app.label_keyboard_value.Text = 'N/A';
            app.label_random_number_value = uilabel(app.UIFigure);
            app.label_random_number_value.Text = 'N/A';
            app.label_frame_3 = uilabel(app.UIFigure);
            app.label_frame_3.Text = '';
            app.label_gp_reg_00 = uilabel(app.UIFigure);
            app.label_gp_reg_00.Text = 'N/A';
            app.label_gp_reg_01 = uilabel(app.UIFigure);
            app.label_gp_reg_01.Text = 'N/A';
            app.label_gp_reg_02 = uilabel(app.UIFigure);
            app.label_gp_reg_02.Text = 'N/A';
            app.label_gp_reg_03 = uilabel(app.UIFigure);
            app.label_gp_reg_03.Text = 'N/A';
            app.label_gp_reg_04 = uilabel(app.UIFigure);
            app.label_gp_reg_04.Text = 'N/A';
            app.label_gp_reg_05 = uilabel(app.UIFigure);
            app.label_gp_reg_05.Text = 'N/A';
            app.label_gp_reg_06 = uilabel(app.UIFigure);
            app.label_gp_reg_06.Text = 'N/A';
            app.label_gp_reg_07 = uilabel(app.UIFigure);
            app.label_gp_reg_07.Text = 'N/A';
            app.label_gp_reg_08 = uilabel(app.UIFigure);
            app.label_gp_reg_08.Text = 'N/A';
            app.label_gp_reg_09 = uilabel(app.UIFigure);
            app.label_gp_reg_09.Text = 'N/A';
            app.label_gp_reg_10 = uilabel(app.UIFigure);
            app.label_gp_reg_10.Text = 'N/A';
            app.label_gp_reg_11 = uilabel(app.UIFigure);
            app.label_gp_reg_11.Text = 'N/A';
            app.label_gp_reg_12 = uilabel(app.UIFigure);
            app.label_gp_reg_12.Text = 'N/A';
            app.label_gp_reg_13 = uilabel(app.UIFigure);
            app.label_gp_reg_13.Text = 'N/A';
            app.label_gp_reg_14 = uilabel(app.UIFigure);
            app.label_gp_reg_14.Text = 'N/A';
            app.label_gp_reg_15 = uilabel(app.UIFigure);
            app.label_gp_reg_15.Text = 'N/A';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'General Purpose Registers';
            app.label_frame_4 = uilabel(app.UIFigure);
            app.label_frame_4.Text = '';
            app.label_stack_00 = uilabel(app.UIFigure);
            app.label_stack_00.Text = 'N/A';
            app.label_stack_01 = uilabel(app.UIFigure);
            app.label_stack_01.Text = 'N/A';
            app.label_stack_02 = uilabel(app.UIFigure);
            app.label_stack_02.Text = 'N/A';
            app.label_stack_03 = uilabel(app.UIFigure);
            app.label_stack_03.Text = 'N/A';
            app.label_stack_04 = uilabel(app.UIFigure);
            app.label_stack_04.Text = 'N/A';
            app.label_stack_05 = uilabel(app.UIFigure);
            app.label_stack_05.Text = 'N/A';
            app.label_stack_06 = uilabel(app.UIFigure);
            app.label_stack_06.Text = 'N/A';
            app.label_stack_07 = uilabel(app.UIFigure);
            app.label_stack_07.Text = 'N/A';
            app.label_stack_08 = uilabel(app.UIFigure);
            app.label_stack_08.Text = 'N/A';
            app.label_stack_09 = uilabel(app.UIFigure);
            app.label_stack_09.Text = 'N/A';
            app.label_stack_10 = uilabel(app.UIFigure);
            app.label_stack_10.Text = 'N/A';
            app.label_stack_11 = uilabel(app.UIFigure);
            app.label_stack_11.Text = 'N/A';
            app.label_stack_12 = uilabel(app.UIFigure);
            app.label_stack_12.Text = 'N/A';
            app.label_stack_13 = uilabel(app.UIFigure);
            app.label_stack_13.Text = 'N/A';
            app.label_stack_14 = uilabel(app.UIFigure);
            app.label_stack_14.Text = 'N/A';
            app.label_stack_15 = uilabel(app.UIFigure);
            app.label_stack_15.Text = 'N/A';
            app.stack = uilabel(app.UIFigure);
            app.stack.Text = 'Stack';
            app.Label25 = uilabel(app.UIFigure);
            app.Label25.Text = '';
            app.label_opcode_value = uilabel(app.UIFigure);
            app.label_opcode_value.Text = 'N/A';
            app.label_dest_reg_value = uilabel(app.UIFigure);
            app.label_dest_reg_value.Text = 'N/A';
            app.label_src_reg_value = uilabel(app.UIFigure);
            app.label_src_reg_value.Text = 'N/A';
            app.label_immediate_value = uilabel(app.UIFigure);
            app.label_immediate_value.Text = 'N/A';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = '      Op         Dest         Src          Imm   ';
            app.Label28 = uilabel(app.UIFigure);
            app.Label28.Text = 'r00';
            app.Label29 = uilabel(app.UIFigure);
            app.Label29.Text = 'r01';
            app.Label30 = uilabel(app.UIFigure);
            app.Label30.Text = 'r02';
            app.Label31 = uilabel(app.UIFigure);
            app.Label31.Text = 'r03';
            app.Label32 = uilabel(app.UIFigure);
            app.Label32.Text = 'r04';
            app.Label33 = uilabel(app.UIFigure);
            app.Label33.Text = 'r05';
            app.Label34 = uilabel(app.UIFigure);
            app.Label34.Text = 'r06';
            app.Label35 = uilabel(app.UIFigure);
            app.Label35.Text = 'r07';
            app.Label36 = uilabel(app.UIFigure);
            app.Label36.Text = 'r08';
            app.Label37 = uilabel(app.UIFigure);
            app.Label37.Text = 'r09';
            app.Label38 = uilabel(app.UIFigure);
            app.Label38.Text = 'r10';
            app.Label39 = uilabel(app.UIFigure);
            app.Label39.Text = 'r11';
            app.Label40 = uilabel(app.UIFigure);
            app.Label40.Text = 'r12';
            app.Label41 = uilabel(app.UIFigure);
            app.Label41.Text = 'r13';
            app.Label42 = uilabel(app.UIFigure);
            app.Label42.Text = 'r14';
            app.Label43 = uilabel(app.UIFigure);
            app.Label43.Text = 'r15';
            app.Label44 = uilabel(app.UIFigure);
            app.Label44.Text = '00';
            app.Label45 = uilabel(app.UIFigure);
            app.Label45.Text = '01';
            app.Label46 = uilabel(app.UIFigure);
            app.Label46.Text = '02';
            app.Label47 = uilabel(app.UIFigure);
            app.Label47.Text = '03';
            app.Label48 = uilabel(app.UIFigure);
            app.Label48.Text = '04';
            app.Label49 = uilabel(app.UIFigure);
            app.Label49.Text = '05';
            app.Label50 = uilabel(app.UIFigure);
            app.Label50.Text = '06';
            app.Label51 = uilabel(app.UIFigure);
            app.Label51.Text = '07';
            app.Label52 = uilabel(app.UIFigure);
            app.Label52.Text = '08';
            app.Label53 = uilabel(app.UIFigure);
            app.Label53.Text = '09';
            app.Label54 = uilabel(app.UIFigure);
            app.Label54.Text = '10';
            app.Label55 = uilabel(app.UIFigure);
            app.Label55.Text = '11';
            app.Label56 = uilabel(app.UIFigure);
            app.Label56.Text = '12';
            app.Label57 = uilabel(app.UIFigure);
            app.Label57.Text = '13';
            app.Label58 = uilabel(app.UIFigure);
            app.Label58.Text = '14';
            app.Label59 = uilabel(app.UIFigure);
            app.Label59.Text = '15';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FrontPanel()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end